classdef RobotInterface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        ConnectButton          matlab.ui.control.Button
        StartButton            matlab.ui.control.Button
        SpeedEditFieldLabel    matlab.ui.control.Label
        SpeedEditField         matlab.ui.control.NumericEditField
        OutputEditFieldLabel   matlab.ui.control.Label
        OutputEditField        matlab.ui.control.EditField
        ConnectedLampLabel     matlab.ui.control.Label
        ConnectedLamp          matlab.ui.control.Lamp
        StopButton             matlab.ui.control.Button
        MotorRunningLampLabel  matlab.ui.control.Label
        MotorRunningLamp       matlab.ui.control.Lamp
        ReadBarcodeButton      matlab.ui.control.Button
        IdentifymarbleButton   matlab.ui.control.Button
        ResetBarcodeButton     matlab.ui.control.Button
    end


    properties (Access = private)
        lego
        motorGate
        motorBelt
        connection
        colorSensorBar
        colorSensorIdentify
        motorBar
        barcode = []
        typeMaterial = {}
        typeCount = []
        blackBaseColor
    end


    methods (Access = private)

        % Button pushed function: ConnectButton
        function Connect(app, event)
           app.ConnectedLamp.Color = 'k';
           app.connection = false;
           success = 1;
           
           clearvars -global app.lego
           try
               app.lego = legoev3('usb');
               app.ConnectedLamp.Color = 'g';
               app.connection = true;
               
               app.motorBelt = motor(app.lego,'A');
               app.motorGate = motor(app.lego,'B');
               app.motorBar = motor(app.lego,'C');
               
               app.colorSensorBar = colorSensor(app.lego, 1);
               app.colorSensorIdentify = colorSensor(app.lego, 2);
               
               beep(app.lego);
               clearLCD(app.lego);
               app.ConnectButton.Enable = 'off';
           catch ME
               ME
               app.OutputEditField.Value = 'No Ev3 connected in USB port or motor port connections incorrect';
               success = 0;
           end
           
           if success
               app.blackBaseColor = readColorRGB(app.colorSensorIdentify);
           end
           
        end

        % Value changed function: SpeedEditField
        function OnChange(app, event)
            value = app.SpeedEditField.Value;
            if app.connection
                app.motorBelt.Speed = value;
            end
            app.OutputEditField.Value = ['Speed set to ' num2str(value)];
        end

        % Button pushed function: ReadBarcodeButton
        function ReadBarcode(app, event)
            if app.connection && any(find([app.lego.ConnectedSensors{:}] == 'color') == 1)
                
                app.colorSensorBar = colorSensor(app.lego, 1);
                
                app.barcode = BarcodeFunction(app.motorBar, app.colorSensorBar);
                
                barcodeString = num2str(app.barcode(1:3),'%d');
                
                switch barcodeString
                    case '001'
                        app.typeMaterial = [app.typeMaterial,'White Glass (Big/Small)'];
                    case '010'
                        app.typeMaterial = [app.typeMaterial,'Red Glass (Big/Small)'];
                    case '011'
                        app.typeMaterial = [app.typeMaterial,'Blue Glass (Big/Small)'];
                    case '100'
                        app.typeMaterial = [app.typeMaterial,'Steel/HDPE'];
                    otherwise
                        app.OutputEditField.Value = 'invalid barcode';
                        app.typeMaterial = [app.typeMaterial,'Invalid'];
                end
                
                if ~(any(ismember(app.typeMaterial, 'Invalid')))
                    decimalCount = polyval(app.barcode(4:8),2) - 1;
                    app.typeCount = [app.typeCount mod(decimalCount,4) floor(decimalCount/4)];
                else
                    app.typeCount = [app.typeCount 0 0];
                end
                app.OutputEditField.Value = WordJoiner(app.typeMaterial,app.typeCount);
                
            end
        end

        % Button pushed function: StartButton
        function Start(app, event)
            if app.connection
                app.motorBeltunningLamp.Color = 'g';
                
                app.motorBelt.Speed = app.SpeedEditField.Value;
                
                pause(0.5);
                
                start(app.motorBelt);
            
                app.OutputEditField.Value = 'Started';
            else
                app.OutputEditField.Value = 'Ev3 not connected';
            end
        end

        % Button pushed function: StopButton
        function Stop(app, event)
            if app.connection
                app.motorBeltunningLamp.Color = 'k';
                
                app.motorBelt.Speed = 0;
                
                stop(app.motorBelt);
                
                app.OutputEditField.Value = 'Stopped';
            else
                app.OutputEditField.Value = 'Ev3 not connected';
            end
        end

        % Button pushed function: IdentifymarbleButton
        function identifyMarble(app, event)
            if app.connection
                N = 100
                cnt = 0;
                red = [];
                blue = [];
                green = [];
                while cnt < N
                    %dont change these
                    cnt = cnt+1;
                    colors = readColorRGB(app.colorSensorIdentify);
                    red = [red colors(1)-app.blackBaseColor(1)];
                    green = [green colors(2)-app.blackBaseColor(2)];
                    blue = [blue colors(3)-app.blackBaseColor(3)];
                end
                    
                rgb = [mean(red) mean(green) mean(blue)];
                app.OutputEditField.Value = [marbleIdentifier(rgb) num2str(rgb)];
            else
                app.OutputEditField.Value = 'Ev3 not connected';
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.ConnectButton = uibutton(app.UIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @Connect, true);
            app.ConnectButton.Text = 'Connect';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @Start, true);
            app.StartButton.Text = 'Start';
            app.SpeedEditFieldLabel = uilabel(app.UIFigure);
            app.SpeedEditFieldLabel.Text = 'Speed';
            app.SpeedEditField = uieditfield(app.UIFigure, 'numeric');
            app.SpeedEditField.ValueChangedFcn = createCallbackFcn(app, @OnChange, true);
            app.SpeedEditField.Limits = [0 40];
            app.SpeedEditField.ValueDisplayFormat = '%.0f';
            app.SpeedEditField.Value = 20;
            app.OutputEditFieldLabel = uilabel(app.UIFigure);
            app.OutputEditFieldLabel.Text = 'Output';
            app.OutputEditField = uieditfield(app.UIFigure, 'text');
            app.OutputEditField.Editable = 'off';
            app.ConnectedLampLabel = uilabel(app.UIFigure);
            app.ConnectedLampLabel.Text = 'Connected';
            app.ConnectedLamp = uilamp(app.UIFigure);
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @Stop, true);
            app.StopButton.Text = 'Stop';
            app.MotorRunningLampLabel = uilabel(app.UIFigure);
            app.MotorRunningLampLabel.Text = 'Motor Running';
            app.MotorRunningLamp = uilamp(app.UIFigure);
            app.ReadBarcodeButton = uibutton(app.UIFigure, 'push');
            app.ReadBarcodeButton.ButtonPushedFcn = createCallbackFcn(app, @ReadBarcode, true);
            app.ReadBarcodeButton.Text = 'Read Barcode';
            app.IdentifymarbleButton = uibutton(app.UIFigure, 'push');
            app.IdentifymarbleButton.ButtonPushedFcn = createCallbackFcn(app, @identifyMarble, true);
            app.IdentifymarbleButton.Text = 'Identify marble';
            app.ResetBarcodeButton = uibutton(app.UIFigure, 'push');
            app.ResetBarcodeButton.Text = 'Reset Barcode';
        end
    end

    methods (Access = public)

        % Construct app
        function app = RobotInterface

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end